classdef computer_Assignment_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InputsPanel           matlab.ui.container.Panel
        RunButton             matlab.ui.control.Button
        TStopEditField        matlab.ui.control.NumericEditField
        TStopEditFieldLabel   matlab.ui.control.Label
        TstartEditField       matlab.ui.control.NumericEditField
        TstartEditFieldLabel  matlab.ui.control.Label
        n2EditField           matlab.ui.control.NumericEditField
        n2EditFieldLabel      matlab.ui.control.Label
        bnEditField           matlab.ui.control.EditField
        bnEditFieldLabel      matlab.ui.control.Label
        a0EditField           matlab.ui.control.NumericEditField
        a0EditFieldLabel      matlab.ui.control.Label
        n3EditField           matlab.ui.control.NumericEditField
        n3EditFieldLabel      matlab.ui.control.Label
        n1EditField           matlab.ui.control.NumericEditField
        n1EditFieldLabel      matlab.ui.control.Label
        anEditField           matlab.ui.control.EditField
        anEditFieldLabel      matlab.ui.control.Label
        TEditField            matlab.ui.control.NumericEditField
        TEditFieldLabel       matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Axes3                 matlab.ui.control.UIAxes
        Axes2                 matlab.ui.control.UIAxes
        Axes1                 matlab.ui.control.UIAxes
        Axes4                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            TStop = app.TStopEditField.Value;
            TStart = app.TstartEditField.Value;
            n3 = app.n3EditField.Value;
            n2 = app.n2EditField.Value;
            n1 = app.n1EditField.Value;

            bn = str2func(['@(n)' app.bnEditField.Value]);
            an = str2func(['@(n)' app.anEditField.Value]);


            T = app.TEditField.Value;
            a0 = app.a0EditField.Value;
            
           
            t=TStart:0.01:TStop;
            FT1=zeros(size(t));
            FT2=zeros(size(t));
            FT3=zeros(size(t));
            for n=1:(n1)
                FT1=FT1+(an(n)).*cos((2*pi*n*t)/(T))+(bn(n)).*(sin((2*pi*n*t)/(T)));
            end
            for n=1:(n2)
                FT2=FT2+(an(n)).*cos((2*pi*n*t)/(T))+(bn(n)).*(sin((2*pi*n*t)/(T)));
            end
            for n=1:(n3)
                FT3=FT3+(an(n)).*cos((2*pi*n*t)/(T))+(bn(n)).*(sin((2*pi*n*t)/(T)));
            end
            FT1=a0+FT1;
            FT2=a0+FT2;
            FT3=a0+FT3;
               
            hold(app.Axes1, 'on');
            hold(app.Axes2, 'on');
            hold(app.Axes3, 'on');
            hold(app.Axes4, 'on');
            
            plot(app.Axes1, t, FT1, 'r');
            plot(app.Axes2, t, FT2, 'g');
            plot(app.Axes3, t, FT3, 'b');
            plot(app.Axes4, t, FT1, 'r', t, FT2, 'g', t, FT3, 'b');
            
            legend(app.Axes4, 'n=1', 'n=2', 'n=3');
            
            % After plotting
            hold(app.Axes1, 'off');
            hold(app.Axes2, 'off');
            hold(app.Axes3, 'off');
            hold(app.Axes4, 'off');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes4 = uiaxes(app.UIFigure);
            title(app.Axes4, 'Plot of n=1,2, & 3.')
            xlabel(app.Axes4, 'Time')
            ylabel(app.Axes4, 'Signal')
            zlabel(app.Axes4, 'Z')
            app.Axes1 = uiaxes(app.UIFigure);
            title(app.Axes1, 'Plot n=1.')
            xlabel(app.Axes1, 'Time')
            ylabel(app.Axes1, 'Signal')
            zlabel(app.Axes1, 'Z')
            app.Axes2 = uiaxes(app.UIFigure);
            title(app.Axes2, 'Plot n=2.')
            xlabel(app.Axes2, 'TIme')
            ylabel(app.Axes2, 'Signal')
            zlabel(app.Axes2, 'Z')
            app.Axes3 = uiaxes(app.UIFigure);
            title(app.Axes3, 'Plot of n=3.')
            xlabel(app.Axes3, 'TIme')
            ylabel(app.Axes3, 'Signal')
            zlabel(app.Axes3, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.TEditFieldLabel = uilabel(app.InputsPanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.InputsPanel, 'numeric');
            app.anEditFieldLabel = uilabel(app.InputsPanel);
            app.anEditFieldLabel.Text = 'an';
            app.anEditField = uieditfield(app.InputsPanel, 'text');
            app.n1EditFieldLabel = uilabel(app.InputsPanel);
            app.n1EditFieldLabel.Text = 'n1';
            app.n1EditField = uieditfield(app.InputsPanel, 'numeric');
            app.n3EditFieldLabel = uilabel(app.InputsPanel);
            app.n3EditFieldLabel.Text = 'n3';
            app.n3EditField = uieditfield(app.InputsPanel, 'numeric');
            app.a0EditFieldLabel = uilabel(app.InputsPanel);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.InputsPanel, 'numeric');
            app.bnEditFieldLabel = uilabel(app.InputsPanel);
            app.bnEditFieldLabel.Text = 'bn';
            app.bnEditField = uieditfield(app.InputsPanel, 'text');
            app.n2EditFieldLabel = uilabel(app.InputsPanel);
            app.n2EditFieldLabel.Text = 'n2';
            app.n2EditField = uieditfield(app.InputsPanel, 'numeric');
            app.TstartEditFieldLabel = uilabel(app.InputsPanel);
            app.TstartEditFieldLabel.Text = 'T start';
            app.TstartEditField = uieditfield(app.InputsPanel, 'numeric');
            app.TStopEditFieldLabel = uilabel(app.InputsPanel);
            app.TStopEditFieldLabel.Text = 'T Stop';
            app.TStopEditField = uieditfield(app.InputsPanel, 'numeric');
            app.RunButton = uibutton(app.InputsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = computer_Assignment_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end